classdef engineDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ChamberOutputsPanel             matlab.ui.container.Panel
        InitialOxidiserMassFlowRateEditFieldLabel  matlab.ui.control.Label
        InitialOxidiserMassFlowRateEditField  matlab.ui.control.NumericEditField
        CStarEditFieldLabel             matlab.ui.control.Label
        CStarEditField                  matlab.ui.control.NumericEditField
        PortLengthEditFieldLabel        matlab.ui.control.Label
        PortLengthEditField             matlab.ui.control.NumericEditField
        InitialPortDiameterEditFieldLabel  matlab.ui.control.Label
        InitialPortDiameterEditField    matlab.ui.control.NumericEditField
        FuelWebThicknessEditFieldLabel  matlab.ui.control.Label
        FuelWebThicknessEditField       matlab.ui.control.NumericEditField
        FinalPortDiameterEditFieldLabel  matlab.ui.control.Label
        FinalPortDiameterEditField      matlab.ui.control.NumericEditField
        InputsPanel                     matlab.ui.container.Panel
        ThrustEditFieldLabel            matlab.ui.control.Label
        ThrustEditField                 matlab.ui.control.NumericEditField
        TotalImpulseEditFieldLabel      matlab.ui.control.Label
        TotalImpulseEditField           matlab.ui.control.NumericEditField
        CombustionConfigurationButton   matlab.ui.control.Button
        InjectorConfigurationButton     matlab.ui.control.Button
        InjectorOutputsPanel            matlab.ui.container.Panel
        InjectorTotalAreaEditFieldLabel  matlab.ui.control.Label
        InjectorTotalAreaEditField      matlab.ui.control.NumericEditField
        InjectorHoleDiameterEditFieldLabel  matlab.ui.control.Label
        InjectorHoleDiameterEditField   matlab.ui.control.NumericEditField
        NumberofHolesEditFieldLabel     matlab.ui.control.Label
        NumberofHolesEditField          matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
        NozzleConfigButton              matlab.ui.control.Button
        FullOutputTab                   matlab.ui.container.Tab
        InitialRegressionRateEditField_2Label  matlab.ui.control.Label
        InitialRegressionRateEditField  matlab.ui.control.NumericEditField
        FuelMassEditField_2Label        matlab.ui.control.Label
        FuelMassEditField               matlab.ui.control.NumericEditField
        OxidiserMassEditField_2Label    matlab.ui.control.Label
        OxidiserMassEditField           matlab.ui.control.NumericEditField
        InitialFuelMassFlowRateEditField_2Label  matlab.ui.control.Label
        InitialFuelMassFlowRateEditField  matlab.ui.control.NumericEditField
        ThroatAreaEditField_2Label      matlab.ui.control.Label
        ThroatAreaEditField             matlab.ui.control.NumericEditField
        ExitAreaEditField_2Label        matlab.ui.control.Label
        ExitAreaEditField               matlab.ui.control.NumericEditField
        ExpansionRatioEditField_2Label  matlab.ui.control.Label
        ExpansionRatioEditField         matlab.ui.control.NumericEditField
        CombustionConfigTab             matlab.ui.container.Tab
        ChamberPressureEditFieldLabel   matlab.ui.control.Label
        ChamberPressureEditField        matlab.ui.control.NumericEditField
        NozzleEfficiencyEditFieldLabel  matlab.ui.control.Label
        NozzleEfficiencyEditField       matlab.ui.control.NumericEditField
        FuelDensityEditFieldLabel       matlab.ui.control.Label
        FuelDensityEditField            matlab.ui.control.NumericEditField
        CombustionEfficiencyetacEditFieldLabel  matlab.ui.control.Label
        CombustionEfficiencyetacEditField  matlab.ui.control.NumericEditField
        MaxOxidiserMassFluxEditFieldLabel  matlab.ui.control.Label
        MaxOxidiserMassFluxEditField    matlab.ui.control.NumericEditField
        OFRatioEditFieldLabel           matlab.ui.control.Label
        OFRatioEditField                matlab.ui.control.NumericEditField
        InitialIspEditFieldLabel        matlab.ui.control.Label
        InitialIspEditField             matlab.ui.control.NumericEditField
        TOxEditFieldLabel               matlab.ui.control.Label
        TOxEditField                    matlab.ui.control.NumericEditField
        RegressionRateParametersPanel   matlab.ui.container.Panel
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        InjectorConfigTab               matlab.ui.container.Tab
        NumberofOrificesEditFieldLabel  matlab.ui.control.Label
        NumberofOrificesEditField       matlab.ui.control.NumericEditField
        OrificeTypeLabel                matlab.ui.control.Label
        OrificeType                     matlab.ui.control.DropDown
        TankConfigTab                   matlab.ui.container.Tab
        OxidiserTankMassEditFieldLabel  matlab.ui.control.Label
        OxidiserTankMassEditField       matlab.ui.control.NumericEditField
        TankHeightEditFieldLabel        matlab.ui.control.Label
        TankHeightEditField             matlab.ui.control.NumericEditField
        TankRadiusEditFieldLabel        matlab.ui.control.Label
        TankRadiusEditField             matlab.ui.control.NumericEditField
        CharacteristicPipeLengthEditFieldLabel  matlab.ui.control.Label
        CharacteristicPipeLengthEditField  matlab.ui.control.NumericEditField
        SimulationConfigTab             matlab.ui.container.Tab
        ConvergenceThresholdEditFieldLabel  matlab.ui.control.Label
        ConvergenceThresholdEditField   matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        combustionRun = 0;
        injectorRun = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            clc
            app.ThrustEditField.Value = 1000;
            app.TotalImpulseEditField.Value = 13500;
            app.ChamberPressureEditField.Value = 35e5;
            app.NozzleEfficiencyEditField.Value = 0.9;
            app.FuelDensityEditField.Value = 993;
            app.CombustionEfficiencyetacEditField.Value = 0.85;
            app.MaxOxidiserMassFluxEditField.Value = 350;
            app.OFRatioEditField.Value = 7;
            app.InitialIspEditField.Value = 185;
            app.aEditField.Value = 0.000236;
            app.nEditField.Value = 0.605;
            app.mEditField.Value = 0;
            app.NumberofOrificesEditField.Value = 6;
            %app.ThrustStatesSpinner.Value = 1;
            app.OrificeType.Value = app.OrificeType.Items(1);
            app.OxidiserTankMassEditField.Value = 10;
            app.TankHeightEditField.Value = 0.8;
            app.TankRadiusEditField.Value = 0.152;
            app.CharacteristicPipeLengthEditField.Value = 0.5;
            app.TOxEditField.Value = 30;
            app.TimeStepEditField.Value = 0.01;
            app.ConvergenceThresholdEditField.Value = 1e-6;
            addpath(genpath('NitrousModelling\'));
        end

        % Button pushed function: CombustionConfigurationButton
        function CombustionConfigurationButtonPushed(app, event)
            app.combustionRun = 1;
            %% Unpacking

            %Universal Constants
            g0    = 9.81;
            bar   = 1e5;
            P_amb = 1*bar;
            
            I_total = app.TotalImpulseEditField.Value;
            F_init = app.ThrustEditField.Value;
            t_burn  = I_total/F_init;
            
            P_cc     = app.ChamberPressureEditField.Value;
            %lambda   = rocketDesignParameters.lambda;
            rho_fuel = app.FuelDensityEditField.Value;
            etac     = app.CombustionEfficiencyetacEditField.Value;
            GO_max   = app.MaxOxidiserMassFluxEditField.Value;
            
            %InitialConfigVars
            OF       = app.OFRatioEditField.Value;
            T_req    = app.TOxEditField.Value;
            Isp_init = app.InitialIspEditField.Value;
            Isp_avg  = Isp_init*0.98;
            
            
            %regRate
            a = app.aEditField.Value;
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            
            %% Start configuration
            
            
            %% Choose a OF, Then Mass and mass flows
            
            m_prop = I_total/(Isp_avg*g0); %[kg] RPE Ch.2
            m_ox = m_prop*OF/(OF+1);
            m_f = m_prop - m_ox;
            
            
            %% Calculate Flow rates
            [P_vap, ~, ~] = nitrous(T_req);
            
            P_vap = P_vap*bar; %convert to Pascals
            
            mdot_propinit = F_init/(Isp_init*g0);    %initial mass flow rate (SPAD eq 7.79)
            mdot_fuelinit = mdot_propinit/(1+OF); %[SPAD, eq 7.79]
            mdot_oxinit = mdot_propinit-mdot_fuelinit; %[SPAD, eq 7.79]
            
            %% Determine C* Cstar [PROPEP?]
            
            
            %This function calculates the values below.
            %[T_flame, gamma, m_mol, R,c_star] = thermochem(OF,P_cc,etac);
            [T_flame, gamma, m_mol, R,c_star] = paraffinThermo(etac);
            
            %gamma = 1.24; % ratio of specific heats (guess, but should be properly determined based on chosen O/F)
            %m_mol = 0.0262109; %Molar mass (kg/mol)
            %R = 8.314/m_mol; %Specific Gas Constant [SPAD, eq 7 .72]
            %T_flame = 3300; %[K] Guess!! check with [Propep]
            %c_star = etac*sqrt(gamma*R*T_flame)/(gamma*(2/(gamma+1))^((gamma+1)/(2*gamma-2))); %characteristic velocity [SPAD, eq 7.71]
            
            
            %% Configure Combustion Port
            %assume single cylindrical port
            
            GO_init = GO_max; %[kg/(m^2*s)] initial oxidiser flow flux [SPAD says blow-off/flooding limit is usually at 350-700 kg/m^2 s, so we used 350 to be safe]
            
            A_port = mdot_oxinit/GO_init; %[m^2] [SPAD, eq. 7.82]
            
            Diameter_port_init = 2*sqrt(A_port/pi); %[m] initial port diameter
            Perimeter_port = pi*Diameter_port_init;
                    
            %Should perform a human check on the suitability of these numbers
            
            GF_init = GO_init/OF; %initial fuel flow flux [SPAD, eq 7.83]
            
            %regression rate formula takes form of:
            % r= a G^n L^m where the coefficients are defined in regRateParams.mat
            
            Lp = (mdot_fuelinit/(rho_fuel*a*(GO_init+GF_init)^n*Perimeter_port))^(1/(m+1)); %length of port (m) [SPAD, eq 7.88]
            
            
            %only for circular:
            
            Diameter_port_fin = sqrt((4*m_f)/(pi*Lp*rho_fuel)+Diameter_port_init^2);   %final diameter of the port [SPAD, eq 7.95]
            
            fuelweb=(Diameter_port_fin-Diameter_port_init)/2; %thickness of fuel that gets burnt [SPAD, 7.96]
            
            PortParameters = [Diameter_port_fin,fuelweb];
            
            rocketDesign.port.FinalDiameter=Diameter_port_fin;
            rocketDesign.port.InitialFuelWeb=fuelweb;
            
            r = a*((GO_init+GF_init)^n)*(Lp^m); %calculate reg rate for reference
            
            %% determine nozzle area
            
            %Since the combustion chamber diameter (dport final) and pressure is known,
            %we can determine the throat area required to choke the flow.
            
            A_throat = mdot_propinit*etac*sqrt(gamma*R*T_flame)*((gamma+1)/2)^((gamma+1)/(2*gamma-2))/(P_cc*gamma);
            
            %%% Use F = mdot Ve = mdot Me sqrt(gamma R T0/(1+(gamma-1)/2*Me^2)) and
            %%% then solve for Me
            
            syms Me;
            M_exit_target=double(vpasolve(mdot_propinit*Me*sqrt(gamma*R*T_flame/(1+(gamma-1)/2*Me^2)) == F_init, Me, 3)); %solve for required exit mach for desired thrust
            
            [~, ~, ~, ~, expansionRatio] = flowisentropic(gamma, M_exit_target,'mach'); %this is a function in the matlab aerospace toolbox
            
            A_exit = expansionRatio*A_throat;
            
            
            %% key outputs:
            app.InitialRegressionRateEditField.Value = r;
            
            app.CStarEditField.Value = c_star;
            
            app.FuelMassEditField.Value = m_f;
            
            app.OxidiserMassEditField.Value = m_ox;
            
            app.InitialFuelMassFlowRateEditField.Value = mdot_fuelinit;
            
            app.InitialOxidiserMassFlowRateEditField.Value = mdot_oxinit;
            
            app.PortLengthEditField.Value = Lp;
            
            app.ThroatAreaEditField.Value = A_throat;
            
            app.ExitAreaEditField.Value = A_exit;
            
            app.ExpansionRatioEditField.Value = expansionRatio;

            rocketDesign.port.FinalDiameter=Diameter_port_fin;
            
            app.FuelWebThicknessEditField.Value = fuelweb;
            
            app.InitialPortDiameterEditField.Value = Diameter_port_init;
            
            app.FinalPortDiameterEditField.Value = Diameter_port_fin;
            
            app.combustionRun = 1;
        end

        % Button pushed function: InjectorConfigurationButton
        function InjectorConfigurationButtonPushed(app, event)
            if app.combustionRun ~= 1
                uialert(app.UIFigure,"Please run Combustion Config first.","Error")
                return
            end
            %% Unpacking

            n = app.NumberofOrificesEditField.Value;
            throttle_states = 1;
            m_dot_nominal = app.InitialOxidiserMassFlowRateEditField.Value;
            orifice = app.OrificeType.Value;
            P_cc = app.ChamberPressureEditField.Value;
            
            dP = 0.2*P_cc; % 20% chamber pressure drop across injector
            
            rho = 725.3193;
            
            %% Setting head loss factor
            if isequal(orifice,'Sharp')
                
                k = 1.7; % Head loss factor, sharp-edged orifices
            
            elseif isequal(orifice,'Radiused')
                
                k = 1.3;
                
            else
                
                disp('Improper injector orifice type selection, please check spelling and supported orifice types')
                return
                
            end
            
            %% Throttling limits
            % Sets the mass flow rates for different specified throttle states.
            for i  = 1 : length(throttle_states)
                
                m_dot(i) = m_dot_nominal*throttle_states(i);
            
            end
            
            %% Unit Conversion
            m_doti = convmass(m_dot,'kg','lbm');
            dPi = convpres(dP,'Pa','psi');
            rhoi = convdensity(rho,'lbm/ft^3','kg/m^3');
            
            %% Area Calculation
            % Gives total injector area required, across all orifices
            
            Ai = m_doti.*sqrt((2.238*k)/(rhoi*dPi)); %result in in^2
            
            A = 0.00064516*Ai; % convert from in^2 to m^2
            A_small = A.*1000000; %mm^2 , good for debug
            
            %% Diameter Calculation
            % Gives the orifice diameter required to achieve the correct pressure drop
            % across the injector.
            
            di = ((3.627*k.*m_doti.^2)./(rhoi*dPi.*n.^2)).^0.25; %result in inches
            
            d = convlength(di,'in','m'); % convert from in to m
            d_small = d.*1000; %mm, good for debug
            
            %% Injector Velocity Calculation
            % WARNING: little information is given on this calculation in the source,
            % proceed with caution
            
            Vi = ((144.*m_doti)./(Ai.*rhoi)); % ft/s
            
            V = convvel(Vi,'ft/s','m/s');
            
            %% Set Outputs
             app.InjectorTotalAreaEditField.Value  = A;
             app.InjectorHoleDiameterEditField.Value = d;
             %app.injectorVelocityEditField.Value = V;
             %app.UITable.Data = table(A,d,V);
            
            app.injectorRun = 1;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % check that config has been run first
            if app.combustionRun ~= 1
                uialert(app.UIFigure,"Please run Combustion Config first.","Error")
                return
            elseif app.injectorRun ~= 1
                uialert(app.UIFigure,"Please run Injector Config first.","Error")
                return
            end
            % extract variables
            
            %universal constants
            g0    = 9.81;
            bar   = 1e5;
            P_amb = 1*bar;
            
            a = app.aEditField.Value;
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            
            etac = app.CombustionEfficiencyetacEditField.Value;
            lambda = app.NozzleEfficiencyEditField.Value;
            
            Lp = app.PortLengthEditField.Value;
            rho_fuel = app.FuelDensityEditField.Value;
            
            P_cc = app.ChamberPressureEditField.Value;
            
            mdot_oxinit = app.InitialOxidiserMassFlowRateEditField.Value;
            mdot_ox = mdot_oxinit; %assumed constant throughout burn
            %m_ox = rocketDesign.m_ox;
            m_ox = 6; %This should be an input...
            A_exit = app.ExitAreaEditField.Value;
            A_throat = app.ThroatAreaEditField.Value;
            expansionRatio = app.ExpansionRatioEditField.Value;
            
            rocketSim.port.diameter = app.InitialPortDiameterEditField.Value;
            rocketSim.port.fuelweb = app.FuelWebThicknessEditField.Value;
            
            orifice = app.OrificeType.Value;
            
            deltaT = app.TimeStepEditField.Value;
            thresh = app.ConvergenceThresholdEditField.Value;
            
            
            %% Setup Tank and Injector Geometries
            
            %Change these parameters to alter the conditions of the chamber/injector
            PDownstream = app.ChamberPressureEditField.Value; %Pressure downstream of orifice (Chamber pressure)
            AInjector1 = 0.25 * pi * (2e-3).^2; %Injector hole 1 cross section in m^3
            AInjector2 = 0.25 * pi * (2e-3).^2; %Injector hole 2 cross section in m^3
            %Discharge coefficient = "ratio of the actual discharge to the theoretical
            %discharge". Ideally empirically determined
            %% Setting head loss factor
            if isequal(orifice,'Sharp')
                dischargeCoefficient1 = 1.7; % Head loss factor, sharp-edged orifices
                dischargeCoefficient2 = 1.7;
            elseif isequal(orifice,'Radiused')
                dischargeCoefficient1 = 1.3;
                dischargeCoefficient2 = 1.3;
            else
                disp('Improper injector orifice type selection, please check spelling and supported orifice types')
                return     
            end
            
%             dischargeCoefficient1 = 0.8; %For injector orifice 1
%             dischargeCoefficient2 = 0.8; %For injector orifice 2
            
            %Change these parameters to alter the conditions of the tank
            initialInternalTankTemp = 27+273.15; %Starting tank temperature in kelvin
            initialInternalNitrousMass = app.OxidiserTankMassEditField.Value; %Kg
            internalTankHeight = app.TankHeightEditField.Value; %Metres, very approximate geometry
            internalTankCrossSectionA = 0.25 * pi * (app.TankRadiusEditField.Value)^2; %M^2, very approximate geometry
            characteristicPipeLength = app.CharacteristicPipeLengthEditField.Value; %0.5m characteristic pipe length
            
            
            %Initialize our tank object and display
            tank = GeometricNitrousTank(initialInternalTankTemp,initialInternalNitrousMass,internalTankHeight,internalTankCrossSectionA,0.65);
            disp("Initial tank pressure: "+tank.vapourPressure);
            disp("Initial tank liquid fraction: "+(tank.liquidHeight/tank.tankHeight));
            
            
            %% peform simulation
            
            qburnfin = 0; %parameter that determines whether the burn is completed or not (0 when not completed, 1 when completed)
            
            t=0;
            ti=1; %time index
            
            P_cc(1) = 30e5;
            
            while qburnfin == 0
                %Get mass flow rate from tank
                
                PTank(ti) = tank.vapourPressure;
                
                if ti == 1
                    PDownstream = P_cc;
                else
                    PDownstream = P_cc(ti-1);
                end
            
                if(tank.vapourPressure <= PDownstream)
                    mdot1(ti) = 0;
                    mdot2(ti) = 0;
                    continue;
                end
                
                [~,~,~,~,G] = SaturatedNitrous.getDownstreamSaturatedNHNEFlowCond(0,tank.temp,tank.vapourPressure,PDownstream,0,characteristicPipeLength);
                mdot1(ti) = G .* AInjector1 .* dischargeCoefficient1;
                mdot2(ti) = G .* AInjector2 .* dischargeCoefficient2;
                MTank(ti) = tank.mTotalNitrous;
                MLiqTank(ti) = tank.mLiquid;
                mdotTotal = mdot1(ti)+mdot2(ti);
                h = NitrousFluidCoolProp.getPropertyForPhase(FluidPhase.LIQUID,FluidProperty.SPECIFIC_ENTHALPY,FluidProperty.PRESSURE,tank.vapourPressure,FluidProperty.VAPOR_QUALITY,0);
                tank.changeNitrousMassEnergy(-mdotTotal.*deltaT,-h.*mdotTotal.*deltaT);
                
                mdot_ox = mdotTotal;
                disp(mdot_ox)
            
                % 2. determine fuel regression rate & % 3. determine total mass flow rate
                
                % Calculating area, perimeter of current port diameter
                [A_port, P_port] = portCalculator(rocketSim.port);
                
                G_ox(ti) = mdot_ox/(A_port);
                
                G_prop(ti) = G_ox(ti); %as a starting point
                
                val(1) = G_prop(ti); %stored temporarily to check for convergence
                
                for loopind = 1:1000
                    
                    S(ti) = P_port*Lp;
                    
                    rdot(ti) = a*G_prop(ti)^n*Lp^m;
                    
                    
                    mdot_fuel(ti) = rdot(ti)*rho_fuel*S(ti);
                    
                    
                    G_fuel(ti) = mdot_fuel(ti)./(A_port); %#ok<*SAGROW>
                    
                    G_prop(ti) = G_ox(ti)+G_fuel(ti);
                    
                    val(loopind+1) = G_prop(ti);
                    
                    if abs(val(loopind)-val(loopind+1))/val(loopind)<thresh
                        break;
                    end
                    
                end
                
                mdot_prop(ti)=mdot_ox+mdot_fuel(ti); %[kg/s]
                
                % 4. determine thermochemistry
                clear val
                
                val(1) = P_cc(max([1,ti-1]));
                
                for loopind = 1:1000
                    
                    P_cc(ti) = val(loopind);
                    
                    OF(ti)=mdot_ox/mdot_fuel(ti);
                    
                    [T_flame(ti), gamma(ti), m_mol(ti), R(ti), c_star(ti)] = thermochem(OF(ti),P_cc(ti),etac); % runs a script called thermochem to determine these numbers.
                    
                    T_stag(ti) = T_flame(ti); %Approximation! Need better formula for stagnation temp of flow - turns out this is pretty good
                    
                    
                    % 5. determine eng perf
                    
                    P_cc(ti) = mdot_prop(ti)*c_star(ti)/A_throat; %assume there is no throat erosion for now - should be updated
                    
                    val(loopind+1) = P_cc(ti);
                    
                    if abs(val(loopind)-val(loopind+1))/val(loopind)<thresh
                        break;
                    end
                    
                end
                
                P_stag(ti) = P_cc(ti); %approximation! need to check if better formula exists.
                
                [M_exit(ti), Tratio(ti), Pratio(ti), rhoratio(ti), ~] = flowisentropic(gamma(ti), expansionRatio, 'sup');
                
                T_exit(ti) = T_stag(ti)*Tratio(ti);
                P_exit(ti) = P_stag(ti)*Pratio(ti);
                
                v_exit(ti) = M_exit(ti)*sqrt(gamma(ti)*R(ti)*T_exit(ti));
                
                F(ti) = lambda*(mdot_prop(ti)*v_exit(ti) + (P_exit(ti)-P_amb)*A_exit); %thrust
                
                Isp(ti) = F(ti)/(mdot_prop(ti)*g0);
                
                % 6. update geometry
            
                rocketSim.port.fuelweb(ti+1)=rocketSim.port.fuelweb(ti)-rdot(ti)*deltaT;
                rocketSim.port.diameter(ti+1)=rocketSim.port.diameter(ti)+rdot(ti)*deltaT;
            
                
                %throat erosion
                
                       
                ti=ti+1;        %increment the time index
                t=t+deltaT;     %increment the time
                
                % 8. check if burn is completed, and go to next step
                
                %qburnfin = checkburnfin(); % a full function could be written to
                %perform this check properly.At the moment it just checks for burn
                %time completion.
                
                %Things to check:
                % (1) chamber pressure < ox tank pressure
                % (2) Choked flow
                % (3) fuel is not entirely eaten up
                
                if mdot_ox*t > m_ox %oxidiser used up
                    qburnfin=1;
                    disp('Ox used up');
                else
                    if rocketSim.port.fuelweb(end)<=0 %using only condition 3 for now
                        qburnfin=1;
                        disp('Fuel Web used up')
                    else
                        if tank.mLiquid <= 0.01
                            qburnfin=1;
                        else
                            qburnfin=0;
                        end
                    end
                end


            end
            
            
            %% store results
            
            rocketSim.F = F;
            rocketSim.Isp= Isp;
            rocketSim.mdot_fuel=mdot_fuel;
            rocketSim.mdot_prop=mdot_prop;
            rocketSim.c_star=c_star;
            rocketSim.G_ox=G_ox;
            rocketSim.G_fuel=G_fuel;
            rocketSim.G_prop= G_prop;
            rocketSim.M_exit=M_exit;
            rocketSim.OF = OF;
            rocketSim.P_cc = P_cc;
            rocketSim.P_exit=P_exit;
            
            
            rocketPerformance.I_total_result = sum(F)*deltaT;
            
            rocketPerformance.t_burn_result = t;
            
            rocketPerformance.F_init_result  = F(1);
            
            rocketPerformance.F_avg_result  = mean(F);
            
            rocketPerformance.Isp_avg = mean(Isp);
            
            rocketPerformance.m_f_total = sum(mdot_fuel)*deltaT;
            
            rocketPerformance.m_ox_total = mdot_ox*rocketPerformance.t_burn_result;
            
            rocketPerformance.m_prop_total = sum(mdot_prop)*deltaT;

        end

        % Value changed function: ThrustEditField
        function ThrustEditFieldValueChanged(app, event)
            value = app.ThrustEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: TotalImpulseEditField
        function TotalImpulseEditFieldValueChanged(app, event)
            value = app.TotalImpulseEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: ChamberPressureEditField
        function ChamberPressureEditFieldValueChanged(app, event)
            value = app.ChamberPressureEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: NozzleEfficiencyEditField
        function NozzleEfficiencyEditFieldValueChanged(app, event)
            value = app.NozzleEfficiencyEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: FuelDensityEditField
        function FuelDensityEditFieldValueChanged(app, event)
            value = app.FuelDensityEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: CombustionEfficiencyetacEditField
        function CombustionEfficiencyetacEditFieldValueChanged(app, event)
            value = app.CombustionEfficiencyetacEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: MaxOxidiserMassFluxEditField
        function MaxOxidiserMassFluxEditFieldValueChanged(app, event)
            value = app.MaxOxidiserMassFluxEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: OFRatioEditField
        function OFRatioEditFieldValueChanged(app, event)
            value = app.OFRatioEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: InitialIspEditField
        function InitialIspEditFieldValueChanged(app, event)
            value = app.InitialIspEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: TOxEditField
        function TOxEditFieldValueChanged(app, event)
            value = app.TOxEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.combustionRun = 0;
            app.injectorRun = 0;
        end

        % Value changed function: NumberofOrificesEditField
        function NumberofOrificesEditFieldValueChanged(app, event)
            value = app.NumberofOrificesEditField.Value;
            app.injectorRun = 0;
        end

        % Value changed function: OrificeType
        function OrificeTypeValueChanged(app, event)
            value = app.OrificeType.Value;
            app.injectorRun = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChamberOutputsPanel = uipanel(app.MainTab);
            app.ChamberOutputsPanel.Title = 'Chamber Outputs';
            app.InitialOxidiserMassFlowRateEditFieldLabel = uilabel(app.ChamberOutputsPanel);
            app.InitialOxidiserMassFlowRateEditFieldLabel.Text = 'Initial Oxidiser Mass Flow Rate';
            app.InitialOxidiserMassFlowRateEditField = uieditfield(app.ChamberOutputsPanel, 'numeric');
            app.CStarEditFieldLabel = uilabel(app.ChamberOutputsPanel);
            app.CStarEditFieldLabel.Text = 'C Star';
            app.CStarEditField = uieditfield(app.ChamberOutputsPanel, 'numeric');
            app.PortLengthEditFieldLabel = uilabel(app.ChamberOutputsPanel);
            app.PortLengthEditFieldLabel.Text = 'Port Length';
            app.PortLengthEditField = uieditfield(app.ChamberOutputsPanel, 'numeric');
            app.InitialPortDiameterEditFieldLabel = uilabel(app.ChamberOutputsPanel);
            app.InitialPortDiameterEditFieldLabel.Text = 'Initial Port Diameter';
            app.InitialPortDiameterEditField = uieditfield(app.ChamberOutputsPanel, 'numeric');
            app.FuelWebThicknessEditFieldLabel = uilabel(app.ChamberOutputsPanel);
            app.FuelWebThicknessEditFieldLabel.Text = 'Fuel Web Thickness';
            app.FuelWebThicknessEditField = uieditfield(app.ChamberOutputsPanel, 'numeric');
            app.FinalPortDiameterEditFieldLabel = uilabel(app.ChamberOutputsPanel);
            app.FinalPortDiameterEditFieldLabel.Text = 'Final Port Diameter';
            app.FinalPortDiameterEditField = uieditfield(app.ChamberOutputsPanel, 'numeric');
            app.InputsPanel = uipanel(app.MainTab);
            app.InputsPanel.Title = 'Inputs';
            app.ThrustEditFieldLabel = uilabel(app.InputsPanel);
            app.ThrustEditFieldLabel.Text = 'Thrust';
            app.ThrustEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ThrustEditField.ValueChangedFcn = createCallbackFcn(app, @ThrustEditFieldValueChanged, true);
            app.TotalImpulseEditFieldLabel = uilabel(app.InputsPanel);
            app.TotalImpulseEditFieldLabel.Text = 'Total Impulse';
            app.TotalImpulseEditField = uieditfield(app.InputsPanel, 'numeric');
            app.TotalImpulseEditField.ValueChangedFcn = createCallbackFcn(app, @TotalImpulseEditFieldValueChanged, true);
            app.CombustionConfigurationButton = uibutton(app.MainTab, 'push');
            app.CombustionConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @CombustionConfigurationButtonPushed, true);
            app.CombustionConfigurationButton.Text = 'Combustion Configuration';
            app.InjectorConfigurationButton = uibutton(app.MainTab, 'push');
            app.InjectorConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @InjectorConfigurationButtonPushed, true);
            app.InjectorConfigurationButton.Text = 'Injector Configuration';
            app.InjectorOutputsPanel = uipanel(app.MainTab);
            app.InjectorOutputsPanel.Title = 'Injector Outputs';
            app.InjectorTotalAreaEditFieldLabel = uilabel(app.InjectorOutputsPanel);
            app.InjectorTotalAreaEditFieldLabel.Text = 'Injector Total Area';
            app.InjectorTotalAreaEditField = uieditfield(app.InjectorOutputsPanel, 'numeric');
            app.InjectorHoleDiameterEditFieldLabel = uilabel(app.InjectorOutputsPanel);
            app.InjectorHoleDiameterEditFieldLabel.Text = 'Injector Hole Diameter';
            app.InjectorHoleDiameterEditField = uieditfield(app.InjectorOutputsPanel, 'numeric');
            app.NumberofHolesEditFieldLabel = uilabel(app.InjectorOutputsPanel);
            app.NumberofHolesEditFieldLabel.Text = 'Number of Holes';
            app.NumberofHolesEditField = uieditfield(app.InjectorOutputsPanel, 'numeric');
            app.RunSimulationButton = uibutton(app.MainTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.NozzleConfigButton = uibutton(app.MainTab, 'push');
            app.NozzleConfigButton.Text = 'Nozzle Config';
            app.FullOutputTab = uitab(app.TabGroup);
            app.FullOutputTab.Title = 'Full Output';
            app.InitialRegressionRateEditField_2Label = uilabel(app.FullOutputTab);
            app.InitialRegressionRateEditField_2Label.Text = 'Initial Regression Rate';
            app.InitialRegressionRateEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.FuelMassEditField_2Label = uilabel(app.FullOutputTab);
            app.FuelMassEditField_2Label.Text = 'Fuel Mass';
            app.FuelMassEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.OxidiserMassEditField_2Label = uilabel(app.FullOutputTab);
            app.OxidiserMassEditField_2Label.Text = 'Oxidiser Mass';
            app.OxidiserMassEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.InitialFuelMassFlowRateEditField_2Label = uilabel(app.FullOutputTab);
            app.InitialFuelMassFlowRateEditField_2Label.Text = 'Initial Fuel Mass Flow Rate';
            app.InitialFuelMassFlowRateEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.ThroatAreaEditField_2Label = uilabel(app.FullOutputTab);
            app.ThroatAreaEditField_2Label.Text = 'Throat Area';
            app.ThroatAreaEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.ExitAreaEditField_2Label = uilabel(app.FullOutputTab);
            app.ExitAreaEditField_2Label.Text = 'Exit Area';
            app.ExitAreaEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.ExpansionRatioEditField_2Label = uilabel(app.FullOutputTab);
            app.ExpansionRatioEditField_2Label.Text = 'Expansion Ratio';
            app.ExpansionRatioEditField = uieditfield(app.FullOutputTab, 'numeric');
            app.CombustionConfigTab = uitab(app.TabGroup);
            app.CombustionConfigTab.Title = 'Combustion Config';
            app.ChamberPressureEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.ChamberPressureEditFieldLabel.Text = 'Chamber Pressure';
            app.ChamberPressureEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.ChamberPressureEditField.ValueChangedFcn = createCallbackFcn(app, @ChamberPressureEditFieldValueChanged, true);
            app.NozzleEfficiencyEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.NozzleEfficiencyEditFieldLabel.Text = 'Nozzle Efficiency';
            app.NozzleEfficiencyEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.NozzleEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @NozzleEfficiencyEditFieldValueChanged, true);
            app.FuelDensityEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.FuelDensityEditFieldLabel.Text = 'Fuel Density';
            app.FuelDensityEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.FuelDensityEditField.ValueChangedFcn = createCallbackFcn(app, @FuelDensityEditFieldValueChanged, true);
            app.CombustionEfficiencyetacEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.CombustionEfficiencyetacEditFieldLabel.Text = 'Combustion Efficiency (etac)';
            app.CombustionEfficiencyetacEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.CombustionEfficiencyetacEditField.ValueChangedFcn = createCallbackFcn(app, @CombustionEfficiencyetacEditFieldValueChanged, true);
            app.MaxOxidiserMassFluxEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.MaxOxidiserMassFluxEditFieldLabel.Text = 'Max Oxidiser Mass Flux';
            app.MaxOxidiserMassFluxEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.MaxOxidiserMassFluxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxOxidiserMassFluxEditFieldValueChanged, true);
            app.OFRatioEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.OFRatioEditFieldLabel.Text = 'OF Ratio';
            app.OFRatioEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.OFRatioEditField.ValueChangedFcn = createCallbackFcn(app, @OFRatioEditFieldValueChanged, true);
            app.InitialIspEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.InitialIspEditFieldLabel.Text = 'Initial Isp';
            app.InitialIspEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.InitialIspEditField.ValueChangedFcn = createCallbackFcn(app, @InitialIspEditFieldValueChanged, true);
            app.TOxEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.TOxEditFieldLabel.Text = 'TOx';
            app.TOxEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.TOxEditField.ValueChangedFcn = createCallbackFcn(app, @TOxEditFieldValueChanged, true);
            app.RegressionRateParametersPanel = uipanel(app.CombustionConfigTab);
            app.RegressionRateParametersPanel.Title = 'Regression Rate Parameters';
            app.aEditFieldLabel = uilabel(app.RegressionRateParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.RegressionRateParametersPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.nEditFieldLabel = uilabel(app.RegressionRateParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.RegressionRateParametersPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.mEditFieldLabel = uilabel(app.RegressionRateParametersPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.RegressionRateParametersPanel, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.InjectorConfigTab = uitab(app.TabGroup);
            app.InjectorConfigTab.Title = 'Injector Config';
            app.NumberofOrificesEditFieldLabel = uilabel(app.InjectorConfigTab);
            app.NumberofOrificesEditFieldLabel.Text = 'Number of Orifices';
            app.NumberofOrificesEditField = uieditfield(app.InjectorConfigTab, 'numeric');
            app.NumberofOrificesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofOrificesEditFieldValueChanged, true);
            app.OrificeTypeLabel = uilabel(app.InjectorConfigTab);
            app.OrificeTypeLabel.Text = 'Orifice Type';
            app.OrificeType = uidropdown(app.InjectorConfigTab);
            app.OrificeType.Items = {'Sharp', 'Radiused'};
            app.OrificeType.ValueChangedFcn = createCallbackFcn(app, @OrificeTypeValueChanged, true);
            app.OrificeType.Value = 'Sharp';
            app.TankConfigTab = uitab(app.TabGroup);
            app.TankConfigTab.Title = 'Tank Config';
            app.OxidiserTankMassEditFieldLabel = uilabel(app.TankConfigTab);
            app.OxidiserTankMassEditFieldLabel.Text = 'Oxidiser Tank Mass';
            app.OxidiserTankMassEditField = uieditfield(app.TankConfigTab, 'numeric');
            app.TankHeightEditFieldLabel = uilabel(app.TankConfigTab);
            app.TankHeightEditFieldLabel.Text = 'Tank Height';
            app.TankHeightEditField = uieditfield(app.TankConfigTab, 'numeric');
            app.TankRadiusEditFieldLabel = uilabel(app.TankConfigTab);
            app.TankRadiusEditFieldLabel.Text = 'Tank Radius';
            app.TankRadiusEditField = uieditfield(app.TankConfigTab, 'numeric');
            app.CharacteristicPipeLengthEditFieldLabel = uilabel(app.TankConfigTab);
            app.CharacteristicPipeLengthEditFieldLabel.Text = 'Characteristic Pipe Length';
            app.CharacteristicPipeLengthEditField = uieditfield(app.TankConfigTab, 'numeric');
            app.SimulationConfigTab = uitab(app.TabGroup);
            app.SimulationConfigTab.Title = 'Simulation Config';
            app.ConvergenceThresholdEditFieldLabel = uilabel(app.SimulationConfigTab);
            app.ConvergenceThresholdEditFieldLabel.Text = 'Convergence Threshold';
            app.ConvergenceThresholdEditField = uieditfield(app.SimulationConfigTab, 'numeric');
            app.TimeStepEditFieldLabel = uilabel(app.SimulationConfigTab);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.SimulationConfigTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = engineDesignApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end